classdef SST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CustomizableEventsPanel      matlab.ui.container.Panel
        CustomEventsTabGroup         matlab.ui.container.TabGroup
        InflammationTab              matlab.ui.container.Tab
        inflammationSwitch           matlab.ui.control.Switch
        NumberofInflammedAxonsLabel  matlab.ui.control.Label
        inflammationAxons            matlab.ui.control.NumericEditField
        TauDecayValue1sLabel         matlab.ui.control.Label
        inflammationTau              matlab.ui.control.NumericEditField
        InflammationOnsetTimesLabel  matlab.ui.control.Label
        inflammationOnset            matlab.ui.control.NumericEditField
        enableLabel                  matlab.ui.control.Label
        HelpDescriptionoftabLabel    matlab.ui.control.Label
        DisturbancesTab              matlab.ui.container.Tab
        disturbancesSwitch           matlab.ui.control.Switch
        enableLabel_2                matlab.ui.control.Label
        MiscellaneousTab             matlab.ui.container.Tab
        miscellaneousSwitch          matlab.ui.control.Switch
        NumberofRecruitedAxonsLabel  matlab.ui.control.Label
        numRecruitedAxons            matlab.ui.control.NumericEditField
        NumberofDismissedAxonsLabel  matlab.ui.control.Label
        numDismissedAxons            matlab.ui.control.NumericEditField
        AxonsPanel                   matlab.ui.container.Panel
        NumberofAxonsLabel           matlab.ui.control.Label
        numAxonsField                matlab.ui.control.NumericEditField
        SamplingRateSamplessLabel    matlab.ui.control.Label
        samplingRateField            matlab.ui.control.NumericEditField
        SimulationTimesLabel         matlab.ui.control.Label
        simulationTimeField          matlab.ui.control.NumericEditField
        MaxPossibleSpikeRatespikessLabel  matlab.ui.control.Label
        maxSpikeRateField            matlab.ui.control.NumericEditField
        overlapCheckBox              matlab.ui.control.CheckBox
        repeatTemplateCheckBox       matlab.ui.control.CheckBox
        RecordingConditionsPanel     matlab.ui.container.Panel
        SignalToNoiseRatioLabel      matlab.ui.control.Label
        SNRField                     matlab.ui.control.NumericEditField
        addDriftCheckBox             matlab.ui.control.CheckBox
        addNoiseCheckBox             matlab.ui.control.CheckBox
        preNoiseCheckBox             matlab.ui.control.CheckBox
        LowbandcutoffHzLabel         matlab.ui.control.Label
        lowBandCutoffField           matlab.ui.control.NumericEditField
        HighbandcutoffHzLabel        matlab.ui.control.Label
        highBandCutoffField          matlab.ui.control.NumericEditField
        filterSignalButton           matlab.ui.control.StateButton
        runButton                    matlab.ui.control.Button
        plotCheckBox                 matlab.ui.control.CheckBox
        saveSimulationButton         matlab.ui.control.Button
        PlotsTabGroup                matlab.ui.container.TabGroup
        MainSimulationTab            matlab.ui.container.Tab
        simulationUIAxes             matlab.ui.control.UIAxes
        IndividualAxonSimulationTab  matlab.ui.container.Tab
        naxonSimulationUIAxes        matlab.ui.control.UIAxes
        TemplateSpikesTab            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        simulation % simulation data of spike train data struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            % Initialize input options
            data = struct;
            data.Naxons      = app.numAxonsField.Value;
            data.SNR         = app.SNRField.Value;
            data.total_time  = app.simulationTimeField.Value;
            data.fs          = app.samplingRateField.Value;
            data.sr          = randi(app.maxSpikeRateField.Value,1,data.Naxons);
            data.overlap     = app.overlapCheckBox.Value;
            data.rpt_temp    = app.repeatTemplateCheckBox.Value;
            data.has_drift   = app.addDriftCheckBox.Value;
            data.has_noise   = app.addNoiseCheckBox.Value;
            data.pre_noise   = app.preNoiseCheckBox.Value;
            data.do_filter   = app.filterSignalButton.Value;
            data.passband    = [app.lowBandCutoffField.Value, app.highBandCutoffField.Value];
            data.PLOT        = app.plotCheckBox.Value;
            
            % Initialize input events
            events_ = struct;
            if app.inflammationSwitch.Value
                events_.inflammation_axons = app.inflammationAxons.Value;
                events_.inflammation_onset = round(app.inflammationOnset.Value * app.samplingRateField.Value);
                events_.inflammation_tau = app.inflammationTau.Value;
            end

            
            % Run main simulator function
            app.simulation = SpikeSimulationTool(data, events_);
            
            % Plot the resultant data in app
            if ~app.plotCheckBox.Value
                % Plot on Main Simulation
                plot(app.simulationUIAxes, app.simulation.time, app.simulation.data);
                
                % Plot on Individual Axon Simulation
                vv = app.simulation.axons;
                max_vv = max(vv);
                [~, srt] = sort(max_vv, 'descend');
                try
                    if ~app.addDriftCheckBox.Value || ~app.preNoiseCheckBox.Value
                        plot(app.naxonSimulationUIAxes, app.simulation.time, vv(:,srt));
                    else
                        plot(app.naxonSimulationUIAxes, app.simulation.time(101:end), vv(:,srt));
                    end
                catch
                    plot(app.naxonSimulationUIAxes, app.simulation.time(101:end), vv(:,srt));
                end
                
                % Plot templates
                app.TemplateSpikesTab.AutoResizeChildren = 'off';
                naxons = size(app.simulation.axons, 2);
                for i = 1 : naxons
                    nr = ceil(sqrt(naxons));
                    nc = ceil(naxons/3);
                    if naxons == 3
                        nc = 1; nr = 3;
                    end
                    ax = subplot(nr,nc,i,'Parent',app.TemplateSpikesTab);
                    template = vv( max(app.simulation.report.locs{i}(1) - 100, 1) : min(app.simulation.report.locs{i}(1) + 100, size(vv,1)), i);
                    plot(ax, template); % subplot axis 'ax' and the plotted data is 'template'
                end
                
            end
            
        end

        % Value changed function: filterSignalButton
        function filterSignalButtonValueChanged(app, event)
            % Enables and disables filtering functionality
            if ~app.filterSignalButton.Value
                app.highBandCutoffField.Enable = 'off';
                app.HighbandcutoffHzLabel.Enable = 'off';
                app.lowBandCutoffField.Enable = 'off';
                app.LowbandcutoffHzLabel.Enable = 'off';
            else
                app.highBandCutoffField.Enable = 'on';
                app.HighbandcutoffHzLabel.Enable = 'on';
                app.lowBandCutoffField.Enable = 'on';
                app.LowbandcutoffHzLabel.Enable = 'on';
            end
        end

        % Button pushed function: saveSimulationButton
        function saveSimulationButtonPushed(app, event)
            % Saves simulation
            [file, path] = uiputfile(['simulations' filesep 'sim.mat'], 'Save file name');
            if file     % checks if file name has been set properly
                file_name = [path filesep file];
                simulation = app.simulation;
                save(file_name, 'simulation');
            else
                errordlg('User did not chose a file location. The simulation was not saved.', 'File error');
            end
        end

        % Value changed function: inflammationSwitch
        function inflammationSwitchValueChanged(app, event)
            % Enables and disables custom value inputs
            if app.inflammationSwitch.Value
                app.inflammationAxons.Enable = 'on';
                app.NumberofInflammedAxonsLabel.Enable = 'on';
                app.inflammationOnset.Enable = 'on';
                app.InflammationOnsetTimesLabel.Enable = 'on';
                app.inflammationTau.Enable = 'on';
                app.TauDecayValue1sLabel.Enable = 'on';
            else
                app.inflammationSwitch.Value
                app.inflammationAxons.Enable = 'off';
                app.NumberofInflammedAxonsLabel.Enable = 'off';
                app.inflammationOnset.Enable = 'off';
                app.InflammationOnsetTimesLabel.Enable = 'off';
                app.inflammationTau.Enable = 'off';
                app.TauDecayValue1sLabel.Enable = 'off';
            end
            
            % Checks if num of inflammed axons exceeds total num of axons
            if app.numAxonsField.Value < app.inflammationAxons.Value
                app.inflammationAxons.Value = app.numAxonsField.Value;
                warn_msg = ['Number of Inflammed Axons must be <= Number of Axons = ' num2str(app.numAxonsField.Value)];
                warndlg(warn_msg);
            end
            
            % Checks inflammation onset is within the simulation time
            if (app.inflammationOnset.Value < 0) || (app.inflammationOnset.Value > app.simulationTimeField.Value)
                app.inflammationOnset.Value = floor(app.simulationTimeField.Value / 2); % default to half the total time
                warn_msg = ['Inflammation onset must be within 0[s] and total simulation time = ' num2str(app.simulationTimeField.Value) '[s]'];
                warndlg(warn_msg);
            end
            
        end

        % Value changed function: inflammationAxons
        function inflammationAxonsValueChanged(app, event)
            % Make it a whole number
            app.inflammationAxons.Value = round(app.inflammationAxons.Value);
            
            % Checks if num of inflammed axons exceeds total num of axons
            if app.numAxonsField.Value < app.inflammationAxons.Value
                app.inflammationAxons.Value = app.numAxonsField.Value;
                warn_msg = ['Number of Inflammed Axons must be <= Number of Axons = ' num2str(app.numAxonsField.Value)];
                warndlg(warn_msg);
            end
        end

        % Value changed function: numAxonsField
        function numAxonsFieldValueChanged(app, event)
            % Make it a whole number
            app.numAxonsField.Value = round(app.numAxonsField.Value);
            
            % Checks if num of inflammed axons exceeds total num of axons
            if (app.numAxonsField.Value < app.inflammationAxons.Value) && strcmp(app.inflammationSwitch.Value, ' ') == 1
                app.numAxonsField.Value = app.inflammationAxons.Value;
                warn_msg = ['Number of Axons must be >= Number of Inflammed Axons = ' num2str(app.inflammationAxons.Value)];
                warndlg(warn_msg);
            end
            
        end

        % Value changed function: simulationTimeField
        function simulationTimeFieldValueChanged(app, event)
            % Checks inflammation onset is within the simulation time
            if ((app.inflammationOnset.Value < 0) || (app.inflammationOnset.Value > app.simulationTimeField.Value)) && strcmp(app.inflammationSwitch.Value, ' ')
                app.inflammationOnset.Value = floor(app.simulationTimeField.Value / 2); % default to half the total time
                warn_msg = ['Inflammation onset must be within 0[s] and total simulation time = ' num2str(app.simulationTimeField.Value) '[s]'];
                warndlg(warn_msg);
            end
        end

        % Value changed function: inflammationOnset
        function inflammationOnsetValueChanged(app, event)
            % Checks inflammation onset is within the simulation time
            if (app.inflammationOnset.Value < 0) || (app.inflammationOnset.Value > app.simulationTimeField.Value)
                app.inflammationOnset.Value = floor(app.simulationTimeField.Value / 2); % default to half the total time
                warn_msg = ['Inflammation onset must be within 0[s] and total simulation time = ' num2str(app.simulationTimeField.Value) '[s]'];
                warndlg(warn_msg);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CustomizableEventsPanel = uipanel(app.UIFigure);
            app.CustomizableEventsPanel.Title = 'Customizable Events (default values if disabled)';
            app.CustomEventsTabGroup = uitabgroup(app.CustomizableEventsPanel);
            app.InflammationTab = uitab(app.CustomEventsTabGroup);
            app.InflammationTab.Title = 'Inflammation';
            app.inflammationSwitch = uiswitch(app.InflammationTab, 'slider');
            app.inflammationSwitch.Items = {'', ' '};
            app.inflammationSwitch.ValueChangedFcn = createCallbackFcn(app, @inflammationSwitchValueChanged, true);
            app.inflammationSwitch.Value = '';
            app.NumberofInflammedAxonsLabel = uilabel(app.InflammationTab);
            app.NumberofInflammedAxonsLabel.Enable = 'off';
            app.NumberofInflammedAxonsLabel.Text = 'Number of Inflammed Axons';
            app.inflammationAxons = uieditfield(app.InflammationTab, 'numeric');
            app.inflammationAxons.ValueChangedFcn = createCallbackFcn(app, @inflammationAxonsValueChanged, true);
            app.inflammationAxons.Enable = 'off';
            app.inflammationAxons.Tooltip = {'Number of inflammed axons must <= Number of Axons'};
            app.inflammationAxons.Value = 5;
            app.TauDecayValue1sLabel = uilabel(app.InflammationTab);
            app.TauDecayValue1sLabel.Enable = 'off';
            app.TauDecayValue1sLabel.Text = 'Tau Decay Value (1/s)';
            app.inflammationTau = uieditfield(app.InflammationTab, 'numeric');
            app.inflammationTau.Enable = 'off';
            app.inflammationTau.Tooltip = {'[insert equation here]'};
            app.inflammationTau.Value = 25;
            app.InflammationOnsetTimesLabel = uilabel(app.InflammationTab);
            app.InflammationOnsetTimesLabel.Enable = 'off';
            app.InflammationOnsetTimesLabel.Text = 'Inflammation Onset Time (s)';
            app.inflammationOnset = uieditfield(app.InflammationTab, 'numeric');
            app.inflammationOnset.ValueChangedFcn = createCallbackFcn(app, @inflammationOnsetValueChanged, true);
            app.inflammationOnset.Enable = 'off';
            app.inflammationOnset.Tooltip = {'Start time of inflammation'};
            app.inflammationOnset.Value = 1;
            app.enableLabel = uilabel(app.InflammationTab);
            app.enableLabel.Text = 'Enable';
            app.HelpDescriptionoftabLabel = uilabel(app.InflammationTab);
            app.HelpDescriptionoftabLabel.Text = 'Help / Description of tab';
            app.DisturbancesTab = uitab(app.CustomEventsTabGroup);
            app.DisturbancesTab.Title = 'Disturbances';
            app.disturbancesSwitch = uiswitch(app.DisturbancesTab, 'slider');
            app.disturbancesSwitch.Items = {'', ''};
            app.disturbancesSwitch.Value = '';
            app.enableLabel_2 = uilabel(app.DisturbancesTab);
            app.enableLabel_2.Text = 'Enable';
            app.MiscellaneousTab = uitab(app.CustomEventsTabGroup);
            app.MiscellaneousTab.Title = 'Miscellaneous';
            app.miscellaneousSwitch = uiswitch(app.MiscellaneousTab, 'slider');
            app.miscellaneousSwitch.Items = {'', ''};
            app.miscellaneousSwitch.Value = '';
            app.NumberofRecruitedAxonsLabel = uilabel(app.MiscellaneousTab);
            app.NumberofRecruitedAxonsLabel.Text = 'Number of Recruited Axons';
            app.numRecruitedAxons = uieditfield(app.MiscellaneousTab, 'numeric');
            app.numRecruitedAxons.Value = 5;
            app.NumberofDismissedAxonsLabel = uilabel(app.MiscellaneousTab);
            app.NumberofDismissedAxonsLabel.Text = 'Number of Dismissed Axons';
            app.numDismissedAxons = uieditfield(app.MiscellaneousTab, 'numeric');
            app.numDismissedAxons.Value = 5;
            app.AxonsPanel = uipanel(app.UIFigure);
            app.AxonsPanel.Title = 'Axons';
            app.NumberofAxonsLabel = uilabel(app.AxonsPanel);
            app.NumberofAxonsLabel.Text = 'Number of Axons';
            app.numAxonsField = uieditfield(app.AxonsPanel, 'numeric');
            app.numAxonsField.ValueChangedFcn = createCallbackFcn(app, @numAxonsFieldValueChanged, true);
            app.numAxonsField.Value = 5;
            app.SamplingRateSamplessLabel = uilabel(app.AxonsPanel);
            app.SamplingRateSamplessLabel.Enable = 'off';
            app.SamplingRateSamplessLabel.Text = 'Sampling Rate (Samples/s)';
            app.samplingRateField = uieditfield(app.AxonsPanel, 'numeric');
            app.samplingRateField.Enable = 'off';
            app.samplingRateField.Tooltip = {'Default value of 5000 should not be changed since template files have this sampling rate'};
            app.samplingRateField.Value = 5000;
            app.SimulationTimesLabel = uilabel(app.AxonsPanel);
            app.SimulationTimesLabel.Text = 'Simulation Time (s)';
            app.simulationTimeField = uieditfield(app.AxonsPanel, 'numeric');
            app.simulationTimeField.ValueChangedFcn = createCallbackFcn(app, @simulationTimeFieldValueChanged, true);
            app.simulationTimeField.Value = 100;
            app.MaxPossibleSpikeRatespikessLabel = uilabel(app.AxonsPanel);
            app.MaxPossibleSpikeRatespikessLabel.Text = 'Max Possible Spike Rate (spikes/s)';
            app.maxSpikeRateField = uieditfield(app.AxonsPanel, 'numeric');
            app.maxSpikeRateField.Value = 5;
            app.overlapCheckBox = uicheckbox(app.AxonsPanel);
            app.overlapCheckBox.Text = 'Spikes can overlap';
            app.repeatTemplateCheckBox = uicheckbox(app.AxonsPanel);
            app.repeatTemplateCheckBox.Text = 'Spikes can have the same template';
            app.repeatTemplateCheckBox.Value = true;
            app.RecordingConditionsPanel = uipanel(app.UIFigure);
            app.RecordingConditionsPanel.Title = 'Recording Conditions';
            app.SignalToNoiseRatioLabel = uilabel(app.RecordingConditionsPanel);
            app.SignalToNoiseRatioLabel.Text = 'Signal To Noise Ratio';
            app.SNRField = uieditfield(app.RecordingConditionsPanel, 'numeric');
            app.SNRField.Value = 20;
            app.addDriftCheckBox = uicheckbox(app.RecordingConditionsPanel);
            app.addDriftCheckBox.Text = 'Add Drift';
            app.addDriftCheckBox.Value = true;
            app.addNoiseCheckBox = uicheckbox(app.RecordingConditionsPanel);
            app.addNoiseCheckBox.Text = 'Add noise';
            app.addNoiseCheckBox.Value = true;
            app.preNoiseCheckBox = uicheckbox(app.RecordingConditionsPanel);
            app.preNoiseCheckBox.Text = 'Append initial noise';
            app.LowbandcutoffHzLabel = uilabel(app.RecordingConditionsPanel);
            app.LowbandcutoffHzLabel.Text = 'Low band cutoff (Hz) :';
            app.lowBandCutoffField = uieditfield(app.RecordingConditionsPanel, 'numeric');
            app.lowBandCutoffField.Value = 40;
            app.HighbandcutoffHzLabel = uilabel(app.RecordingConditionsPanel);
            app.HighbandcutoffHzLabel.Text = 'High band cutoff (Hz) :';
            app.highBandCutoffField = uieditfield(app.RecordingConditionsPanel, 'numeric');
            app.highBandCutoffField.Value = 120;
            app.filterSignalButton = uibutton(app.RecordingConditionsPanel, 'state');
            app.filterSignalButton.ValueChangedFcn = createCallbackFcn(app, @filterSignalButtonValueChanged, true);
            app.filterSignalButton.Text = 'Filter Signal';
            app.filterSignalButton.Value = true;
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'Run';
            app.plotCheckBox = uicheckbox(app.UIFigure);
            app.plotCheckBox.Text = 'Plot results on separate window';
            app.saveSimulationButton = uibutton(app.UIFigure, 'push');
            app.saveSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @saveSimulationButtonPushed, true);
            app.saveSimulationButton.Text = 'Save Simulation';
            app.PlotsTabGroup = uitabgroup(app.UIFigure);
            app.MainSimulationTab = uitab(app.PlotsTabGroup);
            app.MainSimulationTab.Title = 'Main Simulation';
            app.simulationUIAxes = uiaxes(app.MainSimulationTab);
            title(app.simulationUIAxes, 'Main Simulation')
            xlabel(app.simulationUIAxes, 'Time (s)')
            ylabel(app.simulationUIAxes, 'Amplitude (V)')
            zlabel(app.simulationUIAxes, 'Z')
            app.IndividualAxonSimulationTab = uitab(app.PlotsTabGroup);
            app.IndividualAxonSimulationTab.Title = 'Individual Axon Simulation';
            app.naxonSimulationUIAxes = uiaxes(app.IndividualAxonSimulationTab);
            title(app.naxonSimulationUIAxes, 'Individual Axon Simulation')
            xlabel(app.naxonSimulationUIAxes, 'Time (s)')
            ylabel(app.naxonSimulationUIAxes, 'Amplitude (V)')
            zlabel(app.naxonSimulationUIAxes, 'Z')
            app.TemplateSpikesTab = uitab(app.PlotsTabGroup);
            app.TemplateSpikesTab.Title = 'Template Spikes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end